classdef NeedleInsertion_Galil_4Axes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RobotconnectionPanel            matlab.ui.container.Panel
        RobotIPEditFieldLabel           matlab.ui.control.Label
        RobotIPEditField                matlab.ui.control.EditField
        RobotConnectButton              matlab.ui.control.Button
        RobotConnectionLamp             matlab.ui.control.Lamp
        RobotDisconnectButton           matlab.ui.control.Button
        MotorsOnOffButton               matlab.ui.control.StateButton
        NeedleInsertion4AxisRobotStereoCameraInsertionExperimentLabel  matlab.ui.control.Label
        DataCollectionFBGandStereoCameraPanel  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        CaptureEntireDataButton         matlab.ui.control.Button
        CaptureStereoImageButton        matlab.ui.control.Button
        CollectFBGDataButton            matlab.ui.control.Button
        NumberFBGSamplesEditField       matlab.ui.control.NumericEditField
        FBGSamplesEditFieldLabel        matlab.ui.control.Label
        SaveDirectoryEditField          matlab.ui.control.EditField
        SaveDirectoryEditFieldLabel     matlab.ui.control.Label
        InsertionHoleSpinner            matlab.ui.control.Spinner
        InsertionHoleSpinnerLabel       matlab.ui.control.Label
        InsertionDepthEditField         matlab.ui.control.NumericEditField
        InsertionDepthmmEditFieldLabel  matlab.ui.control.Label
        SensorIDRunEditField            matlab.ui.control.EditField
        SensorIDRunEditFieldLabel       matlab.ui.control.Label
        StatusTextAreaLabel             matlab.ui.control.Label
        DataStatusTextArea              matlab.ui.control.TextArea
        ClearStatusButton               matlab.ui.control.Button
        InterrogatorConnectionPanel     matlab.ui.container.Panel
        InterrogatorConnectButton       matlab.ui.control.Button
        InterrogatorDisconnectButton    matlab.ui.control.Button
        InterrogatorConnectLamp         matlab.ui.control.Lamp
        InterrgatorIPLabel              matlab.ui.control.Label
        InterrogatorIPEditField         matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        RobotControlTab                 matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        XAxisinsertionEditFieldLabel    matlab.ui.control.Label
        XAxisEditField                  matlab.ui.control.NumericEditField
        YAxistowardsyouLabel            matlab.ui.control.Label
        YAxisEditField                  matlab.ui.control.NumericEditField
        ZAxisdownEditFieldLabel         matlab.ui.control.Label
        ZAxisEditField                  matlab.ui.control.NumericEditField
        LinearStageinsertionEditFieldLabel  matlab.ui.control.Label
        LinearStageEditField            matlab.ui.control.NumericEditField
        MoveXButton                     matlab.ui.control.Button
        MoveYButton                     matlab.ui.control.Button
        MoveAllButton                   matlab.ui.control.Button
        MoveZButton                     matlab.ui.control.Button
        MoveLSButton                    matlab.ui.control.Button
        RobotAxisLabel                  matlab.ui.control.Label
        CurrentPositionLabel            matlab.ui.control.Label
        MovementTypeButtonGroup         matlab.ui.container.ButtonGroup
        RelativeButton                  matlab.ui.control.RadioButton
        AbsoluteButton                  matlab.ui.control.RadioButton
        XAxisMoveEditField              matlab.ui.control.NumericEditField
        YAxisMoveEditField              matlab.ui.control.NumericEditField
        ZAxisMoveEditField              matlab.ui.control.NumericEditField
        LinearStageMoveEditField        matlab.ui.control.NumericEditField
        MovementLabel                   matlab.ui.control.Label
        ZeroAxesButton                  matlab.ui.control.Button
        XAxisDirection                  matlab.ui.container.ButtonGroup
        XInsertButton                   matlab.ui.control.RadioButton
        XRetractButton                  matlab.ui.control.RadioButton
        YAxisDirection                  matlab.ui.container.ButtonGroup
        YTowardsButton                  matlab.ui.control.RadioButton
        YAwayButton                     matlab.ui.control.RadioButton
        ZAxisDirection                  matlab.ui.container.ButtonGroup
        ZUpButton                       matlab.ui.control.RadioButton
        ZDowntButton                    matlab.ui.control.RadioButton
        LSAxisDirection                 matlab.ui.container.ButtonGroup
        LSInsertButton                  matlab.ui.control.RadioButton
        LSRetractButton                 matlab.ui.control.RadioButton
        MovementDirectionLabel          matlab.ui.control.Label
        ABORTButton                     matlab.ui.control.Button
        StereoImagesTab                 matlab.ui.container.Tab
        StereoImageAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        galil_controller                % Galil Controller 
        sensor_id_run (1,:) {mustBeInteger} = [1, 4, 7, 10, 2, 5, 8, 11, 3, 6, 9, 12]; % default 4 AA peak needle labeling
        Interrogator_t                  % Interrogator handle
        position_timer timer = timer(); % Timer to update current robot position
    end
    
    methods (Access = private)
        
        function UpdateDisplay(app, str)
            if isempty(app.DataStatusTextArea.Value{end})
                app.DataStatusTextArea.Value{end} = char(strrep(str, '\n', ''));
            else
                app.DataStatusTextArea.Value{end+1} = char(strrep(str, '\n', ''));
            end
        end
        
        function UpdateInsertionDepth(app)
            app.InsertionDepthEditField.Value = app.XAxisEditField.Value + app.LinearStageEditField.Value;
        end
        
        function UpdateRobotPosition(app)
            if ~isempty(app.galil_controller)
                positions = app.galil_controller.currentPosition();
                app.XAxisEditField.Value       = positions(1);
                app.YAxisEditField.Value       = positions(2);
                app.ZAxisEditField.Value       = positions(3);
                app.LinearStageEditField.Value = positions(4);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RobotConnectButton
        function RobotConnectButtonPushed(app, event)
            % get IP Address
            ip_address = app.RobotIPEditField.Value;
            % connect to Galil Controller
            try
                app.galil_controller = GalilController(ip_address);
                app.galil_controller.waitCompletion = false;
                app.RobotConnectionLamp.Color = [0.0, 1.0, 0.0];
                
                pause(1e-3)
                app.galil_controller.allMotorsOff();
                
                % set position handles
                app.position_timer.Period = 0.1;
                app.position_timer.ExecutionMode = 'fixedRate';
                app.position_timer.TimerFcn = @(varargin) app.UpdateRobotPosition();
                start(app.position_timer);
                
            catch err
                disp(err.message);
                app.galil_controller = [];
                app.RobotConnectionLamp.Color = [1.0, 0.0, 0.0];
                stop(app.position_timer)
            end
            
        end

        % Button pushed function: RobotDisconnectButton
        function RobotDisconnectButtonPushed(app, event)
            pnet('closeall');
            clear app.galil_controller;
            app.galil_controller = [];
            app.RobotConnectionLamp.Color = [0.65, 0.65, 0.65];
            stop(app.position_timer)
        end

        % Value changed function: MotorsOnOffButton
        function MotorsOnOffButtonValueChanged(app, event)
            value = app.MotorsOnOffButton.Value;
            if ~isempty(app.galil_controller)
                if value
                    app.galil_controller.motorsOn(true, true, true, true)
                else
                    app.galil_controller.motorsOff(true, true, true, true)
                end
            else
                app.MotorsOnOffButton.Value = false;
            end

        end

        % Button pushed function: InterrogatorConnectButton
        function InterrogatorConnectButtonPushed(app, event)
            try
                ip_address = app.InterrogatorIPEditField.Value;
                app.Interrogator_t = FBGInterrogator(ip_address);
                app.InterrogatorConnectLamp.Color = [0, 1, 0];
            catch
                app.Interrogator_t = [];
                app.InterrogatorConnectLamp.Color = [1, 0, 0];
            end
            
            
        end

        % Button pushed function: InterrogatorDisconnectButton
        function InterrogatorDisconnectButtonPushed(app, event)
            clear app.Interrogator_t;
            app.Interrogator_t = [];
            app.InterrogatorConnectLamp.Color = [0.65, 0.65, 0.65];
        end

        % Value changed function: SensorIDRunEditField
        function SensorIDRunEditFieldValueChanged(app, event)
            value = app.SensorIDRunEditField.Value;
            temp_sensor_id_run = str2double(split(value, ','));
            if all(~isnan(temp_sensor_id_run))
                app.sensor_id_run = temp_sensor_id_run;
           
            end
            
            val = sprintf('%d,', app.sensor_id_run);
            val = strip(val, 'right', ',');
            app.SensorIDRunEditField.Value = val;
        end

        % Button pushed function: CollectFBGDataButton
        function CollectFBGDataButtonPushed(app, event)
            if isempty(app.Interrogator_t)
                return
            end
            
            % grab pertinent information
            N_samples = app.NumberFBGSamplesEditField.Value;
            num_of_sensors = length(app.sensor_id_run);
            
            % prepare data collection
            fbg_data = -1*ones(N_samples, num_of_sensors);
            
            % collect the FBG data
            sample_i = 0;
            while sample_i < N_samples
                sample_i = sample_i + 1; 
                
                % grab a row of FBG data sets
                try
                    [peaks, msg] = app.Interrogator_t.read_peaks();
                catch
                    continue
                end
                
                fbg_data(sample_i, :) = peaks; 
                
            end

            
            % save the data
            data_dir = fullfile(app.SaveDirectoryEditField.Value, ...
                                sprintf("Insertion%d",app.InsertionHoleSpinner.Value),...
                                num2str(app.InsertionDepthEditField.Value));
            mkdir(data_dir);
            fbgfile = fullfile(data_dir, "FBGdata.xls");
            
            writematrix(fbg_data, fbgfile);
            msg = sprintf("Saved FBG data to: %s\n", fbgfile);
            fprintf(msg);
            app.UpdateDisplay(msg);
            
        end

        % Button pushed function: CaptureStereoImageButton
        function CaptureStereoImageButtonPushed(app, event)
            pgr_stereo = py.PgrStereo.PgrStereo();
            pgr_stereo.connect();
            pgr_stereo.startCapture()
        
            % grab and convert the images to MATLAB format (rows x cols x [RGB])
            img_lr = cell(pgr_stereo.grab_image_pair());
            img_lr{1} = double(img_lr{1});
            img_lr{2} = double(img_lr{2});
        
            pgr_stereo.stopCapture()
            pgr_stereo.disconnect();
        
            Il = uint8(img_lr{1});
            Ir = uint8(img_lr{2});
            
            imshow(cat(2, Il, Ir), 'Parent',app.StereoImageAxes)
            
            % Save the data
            data_dir = fullfile(app.SaveDirectoryEditField.Value, ...
                                sprintf("Insertion%d",app.InsertionHoleSpinner.Value),...
                                num2str(app.InsertionDepthEditField.Value));
            mkdir(data_dir);
            
            left_file = fullfile(data_dir, 'left.png');
            right_file = fullfile(data_dir, 'right.png');
            
            imwrite(Il, left_file);
            msg = sprintf("Saved Image: %s\n", left_file);
            fprintf(msg);
            app.UpdateDisplay(msg);
            imwrite(Ir, right_file);
            msg = sprintf("Saved Image: %s\n", right_file);
            fprintf(msg);
            app.UpdateDisplay(msg);
            
        end

        % Button pushed function: CaptureEntireDataButton
        function CaptureEntireDataButtonPushed(app, event)
            CollectFBGDataButtonPushed(app,event);
            CaptureStereoImageButtonPushed(app,event);
        end

        % Selection changed function: MovementTypeButtonGroup
        function MovementTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.MovementTypeButtonGroup.SelectedObject;
            switch selectedButton
                case app.RelativeButton
                    % Change Movement Header Name
                    app.MovementLabel.Text = "Relative Movement (mm)";
                    
                    % reset the limits
                    app.XAxisMoveEditField.Limits       = [0, 50];
                    app.YAxisMoveEditField.Limits       = [0, 50];
                    app.ZAxisMoveEditField.Limits       = [0, 50];
                    app.LinearStageMoveEditField.Limits = [0, 50];
                    
                    % reset the movement values
                    app.XAxisMoveEditField.Value = 0;
                    app.YAxisMoveEditField.Value = 0;
                    app.ZAxisMoveEditField.Value = 0;
                    app.LinearStageMoveEditField.Value = 0;
                    
                    % Turn on movement direction
                    app.XAxisDirection.Enable = 'on';
                    app.YAxisDirection.Enable = 'on';
                    app.ZAxisDirection.Enable = 'on';
                    app.LSAxisDirection.Enable = 'on';
                    app.MovementDirectionLabel.Enable = 'on';
                    
                case app.AbsoluteButton
                    % Change Movement Header Name
                    app.MovementLabel.Text = "Absolute Movement (mm)";
                    
                    % reset the limits
                    app.XAxisMoveEditField.Limits       = [-inf, inf];
                    app.YAxisMoveEditField.Limits       = [-inf, inf];
                    app.ZAxisMoveEditField.Limits       = [-inf, inf];
                    app.LinearStageMoveEditField.Limits = [-inf, inf];
                    
                    % reset the movement values
                    app.XAxisMoveEditField.Value = app.XAxisEditField.Value;
                    app.YAxisMoveEditField.Value = app.YAxisEditField.Value;
                    app.ZAxisMoveEditField.Value = app.ZAxisEditField.Value;
                    app.LinearStageMoveEditField.Value = app.LinearStageEditField.Value;                    
                    
                    % Turn off movement direction
                    app.XAxisDirection.Enable = 'off';
                    app.YAxisDirection.Enable = 'off';
                    app.ZAxisDirection.Enable = 'off';
                    app.LSAxisDirection.Enable = 'off';
                    app.MovementDirectionLabel.Enable = 'off';
            end


        end

        % Button pushed function: ZeroAxesButton
        function ZeroAxesButtonPushed(app, event)
            app.XAxisEditField.Value = 0;
            app.YAxisEditField.Value = 0;
            app.ZAxisEditField.Value = 0;
            app.LinearStageEditField.Value = 0;
            
            if ~isempty(app.galil_controller)
                app.galil_controller.zeroAllAxes();
            end
        end

        % Button pushed function: MoveAllButton
        function MoveAllButtonPushed(app, event)
            if ~isempty(app.galil_controller)
                switch app.MovementTypeButtonGroup.SelectedObject
                    case app.RelativeButton
                        % grab values
                        x_movement = abs(app.XAxisMoveEditField.Value);
                        y_movement = abs(app.YAxisMoveEditField.Value);
                        z_movement = abs(app.ZAxisMoveEditField.Value);
                        ls_movement = abs(app.LinearStageMoveEditField.Value);
                        
                        % reset the values
                        app.XAxisMoveEditField.Value = x_movement;
                        app.YAxisMoveEditField.Value = y_movement;
                        app.ZAxisMoveEditField.Value = z_movement;
                        app.LinearStageMoveEditField.Value = ls_movement;
                        
                        % check for directions
                        if app.XAxisDirection.SelectedObject == app.XRetractButton
                            x_movement = -x_movement;
                        end
                        if app.YAxisDirection.SelectedObject == app.YAwayButton
                            y_movement = -y_movement;
                        end
                        if app.ZAxisDirection.SelectedObject == app.ZUpButton
                            z_movement = -z_movement;
                        end
                        if app.LSAxisDirection == app.LSInsertButton
                            ls_movement = -ls_movement;
                        end
                        
                    case app.AbsoluteButton
                        % grab current positions
                        x_current = app.XAxisEditField.Value;
                        y_current = app.YAxisEditField.Value;
                        z_current = app.ZAxisEditField.Value;
                        ls_current = app.LinearStageEditField.Value;
                        
                        % grab desired positions
                        x_desired = app.XAxisMoveEditField.Value;
                        y_desired = app.YAxisMoveEditField.Value;
                        z_desired = app.ZAxisMoveEditField.Value;
                        ls_desired = app.LinearStageMoveEditField.Value;
                        
                        % determine relative movements
                        x_movement = x_desired - x_current;
                        y_movement = y_desired - y_current;
                        z_movement = z_desired - z_current;
                        ls_movement = ls_desired - ls_current;
                end
                
                try
                    % move the robot
                    app.MoveAllButton.BackgroundColor = [1.0, 1.0, 0.0];
                    app.galil_controller.moveRelative(x_movement, y_movement, z_movement, ls_movement);
                    app.MoveAllButton.BackgroundColor = [0.96, 0.96, 0.96];
                    
                catch err
                    disp(err.message)
                    app.MoveAllButton.BackgroundColor = [1, 0, 0];
                end
                
%                  % Update the fields
%                 app.XAxisEditField.Value = app.XAxisEditField.Value + x_movement;
%                 app.YAxisEditField.Value = app.YAxisEditField.Value + y_movement;
%                 app.ZAxisEditField.Value = app.ZAxisEditField.Value + z_movement;
%                 app.LinearStageEditField.Value = app.LinearStageEditField.Value + ls_movement;
                
            end
        end

        % Button pushed function: MoveXButton
        function MoveXButtonPushed(app, event)
            if ~isempty(app.galil_controller)
                
                switch app.MovementTypeButtonGroup.SelectedObject
                    case app.RelativeButton
                        % grab values
                        app.XAxisMoveEditField.Value = abs(app.XAxisMoveEditField.Value);
                        x_movement = abs(app.XAxisMoveEditField.Value);
                        if app.XAxisDirection.SelectedObject == app.XRetractButton % flip the sign
                            x_movement = -x_movement;
                        end
                                                
                    case app.AbsoluteButton
                        x_current = app.XAxisEditField.Value;
                        x_desired = app.XAxisMoveEditField.Value;
                        x_movement = x_desired - x_current;
                        
                end
                try
                    app.MoveXButton.BackgroundColor = [1.0, 1.0, 0.0];
                    app.galil_controller.moveRelative(x_movement, 0, 0, 0);
                    app.MoveXButton.BackgroundColor = [0.96, 0.96, 0.96];
                    
                catch err
                    disp(err.message)
                    app.MoveXButton.BackgroundColor = [1.0, 0.0, 0.0];
                end
                
%                 % update current position
%                 app.XAxisEditField.Value = app.XAxisEditField.Value + x_movement;
            end
        end

        % Button pushed function: MoveYButton
        function MoveYButtonPushed(app, event)
            if ~isempty(app.galil_controller)
                switch app.MovementTypeButtonGroup.SelectedObject
                    case app.RelativeButton
                        % grab values
                        app.YAxisMoveEditField.Value = abs(app.YAxisMoveEditField.Value);
                        y_movement = abs(app.YAxisMoveEditField.Value);
                        if app.YAxisDirection.SelectedObject == app.YTowardsButton
                            y_movement = -y_movement;
                        end
                        
                    case app.AbsoluteButton
                        y_current = app.YAxisEditField.Value;
                        y_desired = app.YAxisMoveEditField.Value;
                        y_movement = y_desired - y_current;
                        
                end
                try
                    app.MoveYButton.BackgroundColor = [1.0, 1.0, 0.0];
                    app.galil_controller.moveRelative(0, y_movement, 0, 0);
                    app.MoveYButton.BackgroundColor = [0.96, 0.96, 0.96];
                    
                catch err
                    disp(err.message)
                    app.MoveYButton.BackgroundColor = [1.0, 0.0, 0.0];
                end
                
%                 % update current position
%                 app.YAxisEditField.Value = app.YAxisEditField.Value + y_movement;
            end
        end

        % Button pushed function: MoveZButton
        function MoveZButtonPushed(app, event)
            if ~isempty(app.galil_controller)
                app.MoveZButton.BackgroundColor = [1.0, 1.0, 0.0];
                switch app.MovementTypeButtonGroup.SelectedObject
                    case app.RelativeButton
                        % grab values
                        app.ZAxisMoveEditField.Value = abs(app.ZAxisMoveEditField.Value); 
                        z_movement = abs(app.ZAxisMoveEditField.Value);
                        if app.ZAxisDirection.SelectedObject == app.ZUpButton
                            z_movement = -z_movement;
                        end
                                               
                    case app.AbsoluteButton
                        z_current = app.ZAxisEditField.Value;
                        z_desired = app.ZAxisMoveEditField.Value;
                        z_movement = z_desired - z_current;
                        
                end
                try
                    
                    app.galil_controller.moveRelative(0, 0, z_movement, 0);
                    app.MoveZButton.BackgroundColor = [0.96, 0.96, 0.96];
                    
                catch err
                    disp(err.message)
                    app.MoveZButton.BackgroundColor = [1.0, 0.0, 0.0];
                end
%                 % update current position
%                 app.ZAxisEditField.Value = app.ZAxisEditField.Value + z_movement;
            end
        end

        % Button pushed function: MoveLSButton
        function MoveLSButtonPushed(app, event)
             if ~isempty(app.galil_controller)
                app.MoveLSButton.BackgroundColor = [1.0, 1.0, 0.0];
                switch app.MovementTypeButtonGroup.SelectedObject
                    case app.RelativeButton
                        % grab values
                        ls_movement = abs(app.LinearStageMoveEditField.Value);
                        app.LinearStageMoveEditField.Value = ls_movement;
                        if app.LSAxisDirection.SelectedObject == app.LSInsertButton
                            ls_movement = -ls_movement;
                        end                       
                        
                        
                    case app.AbsoluteButton
                        ls_current = app.LinearStageEditField.Value;
                        ls_desired = app.LinearStageMoveEditField.Value;
                        ls_movement = ls_desired - ls_current;
                        
                end
                try
                    
                    app.galil_controller.moveRelative(0, 0, 0, ls_movement);
                    app.MoveLSButton.BackgroundColor = [0.96, 0.96, 0.96];
                    
                    
                catch err
                    disp(err.message)
                    app.MoveLSButton.BackgroundColor = [1.0, 0.0, 0.0];
                end
                
%                 % update current position
%                 app.LinearStageEditField.Value = app.LinearStageEditField.Value + ls_movement;
            end
        end

        % Button pushed function: ClearStatusButton
        function ClearStatusButtonPushed(app, event)
            app.DataStatusTextArea.Value = {''};
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            if ~isempty(app.galil_controller)
                app.galil_controller.abort();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RobotconnectionPanel = uipanel(app.UIFigure);
            app.RobotconnectionPanel.Title = 'Robot connection';
            app.RobotIPEditFieldLabel = uilabel(app.RobotconnectionPanel);
            app.RobotIPEditFieldLabel.Text = 'Robot IP';
            app.RobotIPEditField = uieditfield(app.RobotconnectionPanel, 'text');
            app.RobotIPEditField.Value = '192.168.1.201';
            app.RobotConnectButton = uibutton(app.RobotconnectionPanel, 'push');
            app.RobotConnectButton.ButtonPushedFcn = createCallbackFcn(app, @RobotConnectButtonPushed, true);
            app.RobotConnectButton.Text = 'Connect';
            app.RobotConnectionLamp = uilamp(app.RobotconnectionPanel);
            app.RobotConnectionLamp.Tag = 'ConnectionLamp';
            app.RobotDisconnectButton = uibutton(app.RobotconnectionPanel, 'push');
            app.RobotDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @RobotDisconnectButtonPushed, true);
            app.RobotDisconnectButton.Text = 'Disconnect';
            app.MotorsOnOffButton = uibutton(app.RobotconnectionPanel, 'state');
            app.MotorsOnOffButton.ValueChangedFcn = createCallbackFcn(app, @MotorsOnOffButtonValueChanged, true);
            app.MotorsOnOffButton.Text = 'Motors On/Off';
            app.NeedleInsertion4AxisRobotStereoCameraInsertionExperimentLabel = uilabel(app.UIFigure);
            app.NeedleInsertion4AxisRobotStereoCameraInsertionExperimentLabel.Text = 'Needle Insertion 4-Axis Robot: Stereo Camera Insertion Experiment';
            app.DataCollectionFBGandStereoCameraPanel = uipanel(app.UIFigure);
            app.DataCollectionFBGandStereoCameraPanel.Title = 'Data Collection (FBG and Stereo Camera)';
            app.GridLayout2 = uigridlayout(app.DataCollectionFBGandStereoCameraPanel);
            app.CaptureEntireDataButton = uibutton(app.GridLayout2, 'push');
            app.CaptureEntireDataButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureEntireDataButtonPushed, true);
            app.CaptureEntireDataButton.Text = 'Capture Entire Data';
            app.CaptureStereoImageButton = uibutton(app.GridLayout2, 'push');
            app.CaptureStereoImageButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureStereoImageButtonPushed, true);
            app.CaptureStereoImageButton.Text = 'Capture Stereo Image';
            app.CollectFBGDataButton = uibutton(app.GridLayout2, 'push');
            app.CollectFBGDataButton.ButtonPushedFcn = createCallbackFcn(app, @CollectFBGDataButtonPushed, true);
            app.CollectFBGDataButton.Text = 'Collect FBG Data';
            app.NumberFBGSamplesEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberFBGSamplesEditField.Value = 200;
            app.FBGSamplesEditFieldLabel = uilabel(app.GridLayout2);
            app.FBGSamplesEditFieldLabel.Text = '# FBG Samples';
            app.SaveDirectoryEditField = uieditfield(app.GridLayout2, 'text');
            app.SaveDirectoryEditFieldLabel = uilabel(app.GridLayout2);
            app.SaveDirectoryEditFieldLabel.Text = 'Save Directory';
            app.InsertionHoleSpinner = uispinner(app.GridLayout2);
            app.InsertionHoleSpinner.Limits = [1 10];
            app.InsertionHoleSpinner.Value = 1;
            app.InsertionHoleSpinnerLabel = uilabel(app.GridLayout2);
            app.InsertionHoleSpinnerLabel.Text = 'Insertion Hole';
            app.InsertionDepthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.InsertionDepthmmEditFieldLabel = uilabel(app.GridLayout2);
            app.InsertionDepthmmEditFieldLabel.Text = 'Insertion Depth (mm)';
            app.SensorIDRunEditField = uieditfield(app.GridLayout2, 'text');
            app.SensorIDRunEditField.ValueChangedFcn = createCallbackFcn(app, @SensorIDRunEditFieldValueChanged, true);
            app.SensorIDRunEditField.Value = '1,2,3,4,5,6,7,8,9,10,11,12';
            app.SensorIDRunEditFieldLabel = uilabel(app.GridLayout2);
            app.SensorIDRunEditFieldLabel.Text = 'Sensor ID Run';
            app.StatusTextAreaLabel = uilabel(app.GridLayout2);
            app.StatusTextAreaLabel.Text = 'Status';
            app.DataStatusTextArea = uitextarea(app.GridLayout2);
            app.DataStatusTextArea.Editable = 'off';
            app.ClearStatusButton = uibutton(app.GridLayout2, 'push');
            app.ClearStatusButton.ButtonPushedFcn = createCallbackFcn(app, @ClearStatusButtonPushed, true);
            app.ClearStatusButton.Text = 'Clear Status';
            app.InterrogatorConnectionPanel = uipanel(app.UIFigure);
            app.InterrogatorConnectionPanel.Title = 'Interrogator Connection';
            app.InterrogatorConnectButton = uibutton(app.InterrogatorConnectionPanel, 'push');
            app.InterrogatorConnectButton.ButtonPushedFcn = createCallbackFcn(app, @InterrogatorConnectButtonPushed, true);
            app.InterrogatorConnectButton.Text = 'Connect';
            app.InterrogatorDisconnectButton = uibutton(app.InterrogatorConnectionPanel, 'push');
            app.InterrogatorDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @InterrogatorDisconnectButtonPushed, true);
            app.InterrogatorDisconnectButton.Text = 'Disconnect';
            app.InterrogatorConnectLamp = uilamp(app.InterrogatorConnectionPanel);
            app.InterrgatorIPLabel = uilabel(app.InterrogatorConnectionPanel);
            app.InterrgatorIPLabel.Text = 'Interrgator IP';
            app.InterrogatorIPEditField = uieditfield(app.InterrogatorConnectionPanel, 'text');
            app.InterrogatorIPEditField.Value = '192.168.1.11';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RobotControlTab = uitab(app.TabGroup);
            app.RobotControlTab.Title = 'Robot Control';
            app.GridLayout = uigridlayout(app.RobotControlTab);
            app.XAxisinsertionEditFieldLabel = uilabel(app.GridLayout);
            app.XAxisinsertionEditFieldLabel.Text = {'X Axis'; '(+ insertion)'};
            app.XAxisEditField = uieditfield(app.GridLayout, 'numeric');
            app.XAxisEditField.Editable = 'off';
            app.YAxistowardsyouLabel = uilabel(app.GridLayout);
            app.YAxistowardsyouLabel.Text = {'Y Axis'; '(- towards you)'};
            app.YAxisEditField = uieditfield(app.GridLayout, 'numeric');
            app.YAxisEditField.Editable = 'off';
            app.ZAxisdownEditFieldLabel = uilabel(app.GridLayout);
            app.ZAxisdownEditFieldLabel.Text = {'Z Axis'; '(+ down)'};
            app.ZAxisEditField = uieditfield(app.GridLayout, 'numeric');
            app.ZAxisEditField.Editable = 'off';
            app.LinearStageinsertionEditFieldLabel = uilabel(app.GridLayout);
            app.LinearStageinsertionEditFieldLabel.Text = {'Linear Stage'; '(- insertion)'};
            app.LinearStageEditField = uieditfield(app.GridLayout, 'numeric');
            app.LinearStageEditField.Editable = 'off';
            app.MoveXButton = uibutton(app.GridLayout, 'push');
            app.MoveXButton.ButtonPushedFcn = createCallbackFcn(app, @MoveXButtonPushed, true);
            app.MoveXButton.Text = 'Move X';
            app.MoveYButton = uibutton(app.GridLayout, 'push');
            app.MoveYButton.ButtonPushedFcn = createCallbackFcn(app, @MoveYButtonPushed, true);
            app.MoveYButton.Text = 'Move Y';
            app.MoveAllButton = uibutton(app.GridLayout, 'push');
            app.MoveAllButton.ButtonPushedFcn = createCallbackFcn(app, @MoveAllButtonPushed, true);
            app.MoveAllButton.Text = 'Move All';
            app.MoveZButton = uibutton(app.GridLayout, 'push');
            app.MoveZButton.ButtonPushedFcn = createCallbackFcn(app, @MoveZButtonPushed, true);
            app.MoveZButton.Text = 'Move Z';
            app.MoveLSButton = uibutton(app.GridLayout, 'push');
            app.MoveLSButton.ButtonPushedFcn = createCallbackFcn(app, @MoveLSButtonPushed, true);
            app.MoveLSButton.Text = 'Move LS';
            app.RobotAxisLabel = uilabel(app.GridLayout);
            app.RobotAxisLabel.Text = 'Robot Axis';
            app.CurrentPositionLabel = uilabel(app.GridLayout);
            app.CurrentPositionLabel.Text = 'Current Position (mm)';
            app.MovementTypeButtonGroup = uibuttongroup(app.GridLayout);
            app.MovementTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MovementTypeButtonGroupSelectionChanged, true);
            app.MovementTypeButtonGroup.Title = 'Movement Type';
            app.RelativeButton = uiradiobutton(app.MovementTypeButtonGroup);
            app.RelativeButton.Text = 'Relative';
            app.RelativeButton.Value = true;
            app.AbsoluteButton = uiradiobutton(app.MovementTypeButtonGroup);
            app.AbsoluteButton.Text = 'Absolute';
            app.XAxisMoveEditField = uieditfield(app.GridLayout, 'numeric');
            app.XAxisMoveEditField.Limits = [0 50];
            app.YAxisMoveEditField = uieditfield(app.GridLayout, 'numeric');
            app.YAxisMoveEditField.Limits = [0 50];
            app.ZAxisMoveEditField = uieditfield(app.GridLayout, 'numeric');
            app.ZAxisMoveEditField.Limits = [0 50];
            app.LinearStageMoveEditField = uieditfield(app.GridLayout, 'numeric');
            app.LinearStageMoveEditField.Limits = [0 50];
            app.MovementLabel = uilabel(app.GridLayout);
            app.MovementLabel.Text = 'Relative Movement (mm)';
            app.ZeroAxesButton = uibutton(app.GridLayout, 'push');
            app.ZeroAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroAxesButtonPushed, true);
            app.ZeroAxesButton.Text = 'Zero Axes';
            app.XAxisDirection = uibuttongroup(app.GridLayout);
            app.XInsertButton = uiradiobutton(app.XAxisDirection);
            app.XInsertButton.Text = 'Insert';
            app.XInsertButton.Value = true;
            app.XRetractButton = uiradiobutton(app.XAxisDirection);
            app.XRetractButton.Text = 'Retract';
            app.YAxisDirection = uibuttongroup(app.GridLayout);
            app.YTowardsButton = uiradiobutton(app.YAxisDirection);
            app.YTowardsButton.Text = 'Towards';
            app.YTowardsButton.Value = true;
            app.YAwayButton = uiradiobutton(app.YAxisDirection);
            app.YAwayButton.Text = 'Away';
            app.ZAxisDirection = uibuttongroup(app.GridLayout);
            app.ZUpButton = uiradiobutton(app.ZAxisDirection);
            app.ZUpButton.Text = 'Up';
            app.ZUpButton.Value = true;
            app.ZDowntButton = uiradiobutton(app.ZAxisDirection);
            app.ZDowntButton.Text = 'Down';
            app.LSAxisDirection = uibuttongroup(app.GridLayout);
            app.LSInsertButton = uiradiobutton(app.LSAxisDirection);
            app.LSInsertButton.Text = 'Insert';
            app.LSInsertButton.Value = true;
            app.LSRetractButton = uiradiobutton(app.LSAxisDirection);
            app.LSRetractButton.Text = 'Retract';
            app.MovementDirectionLabel = uilabel(app.GridLayout);
            app.MovementDirectionLabel.Text = 'Movement Direction';
            app.ABORTButton = uibutton(app.GridLayout, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Text = 'ABORT';
            app.StereoImagesTab = uitab(app.TabGroup);
            app.StereoImagesTab.Title = 'Stereo Images';
            app.StereoImageAxes = uiaxes(app.StereoImagesTab);
            title(app.StereoImageAxes, 'Left-Right Image Pair')
            zlabel(app.StereoImageAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeedleInsertion_Galil_4Axes

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end